classdef appApdViewInfo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        APDSerialLabel       matlab.ui.control.Label
        apdSerialEditField   matlab.ui.control.EditField
        DateEditFieldLabel   matlab.ui.control.Label
        dateEditField        matlab.ui.control.EditField
        ControlVSliderLabel  matlab.ui.control.Label
        controlVSlider       matlab.ui.control.Slider
        gainAxes             matlab.ui.control.UIAxes
        irfAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingApp % calling APP
        channel % store which apd is shown
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, apdObj, channel)
            app.UIFigure.Name = ['Channel ' num2str(channel)];
            app.callingApp = mainapp;
            plot(app.gainAxes,apdObj.gainV, apdObj.apdGain,'r.--', 'MarkerSize', 10, 'LineWidth', 1)
            app.gainAxes.YScale = 'log';
            app.gainAxes.XLabel.String = 'Control Voltage (V)';
            app.gainAxes.YLabel.String = 'Gain (a.u.)';
%             app.gainAxes.Title = 'Gain Curve';
            plot(app.irfAxes, apdObj.irf)
            app.apdSerialEditField.Value = apdObj.serialNumber;
            app.dateEditField.Value = apdObj.dateModified;
            app.channel = channel;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            switch app.channel
                case 1
                    app.callingApp.apd1ViewButton.Enable = 'On';
                case 2
                    app.callingApp.apd2ViewButton.Enable = 'On';
                case 3
                    app.callingApp.apd3ViewButton.Enable = 'On';
            end
            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.APDSerialLabel = uilabel(app.UIFigure);
            app.APDSerialLabel.Text = 'APD Serial #';
            app.apdSerialEditField = uieditfield(app.UIFigure, 'text');
            app.DateEditFieldLabel = uilabel(app.UIFigure);
            app.DateEditFieldLabel.Text = 'Date ';
            app.dateEditField = uieditfield(app.UIFigure, 'text');
            app.ControlVSliderLabel = uilabel(app.UIFigure);
            app.ControlVSliderLabel.Text = 'Control V';
            app.controlVSlider = uislider(app.UIFigure);
            app.gainAxes = uiaxes(app.UIFigure);
            title(app.gainAxes, 'Title')
            xlabel(app.gainAxes, 'X')
            ylabel(app.gainAxes, 'Y')
            zlabel(app.gainAxes, 'Z')
            app.irfAxes = uiaxes(app.UIFigure);
            title(app.irfAxes, 'Title')
            xlabel(app.irfAxes, 'X')
            ylabel(app.irfAxes, 'Y')
            zlabel(app.irfAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appApdViewInfo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end